classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        PlotsTab                        matlab.ui.container.Tab
        PlotButton                      matlab.ui.control.Button
        SavePlotsButton                 matlab.ui.control.Button
        CopyplotsButton                 matlab.ui.control.Button
        CloseAllFiguresButton           matlab.ui.control.Button
        EnterFigureNameEditFieldLabel   matlab.ui.control.Label
        EnterFigureNameEditField        matlab.ui.control.EditField
        ListBox                         matlab.ui.control.ListBox
        CloseFigureButton               matlab.ui.control.Button
        ClearFigureButton               matlab.ui.control.Button
        GSEControllerPanel_2            matlab.ui.container.Panel
        gse_valve_channels_text_area    matlab.ui.control.TextArea
        ValveChannelsTextAreaLabel      matlab.ui.control.Label
        gse_pts_channels_text_area      matlab.ui.control.TextArea
        PTChannelsTextAreaLabel         matlab.ui.control.Label
        PressBoardPanel                 matlab.ui.container.Panel
        PTChannelsTextArea_2Label       matlab.ui.control.Label
        ec_pts_channels_text_area       matlab.ui.control.TextArea
        ec_valve_channels_text_area     matlab.ui.control.TextArea
        ValveChannelsTextArea_2Label    matlab.ui.control.Label
        GSEControllerPanel              matlab.ui.container.Panel
        PTsLabel                        matlab.ui.control.Label
        ValvesLabel                     matlab.ui.control.Label
        checkbox_gse_pt_1               matlab.ui.control.CheckBox
        checkbox_gse_pt_2               matlab.ui.control.CheckBox
        checkbox_gse_pt_4               matlab.ui.control.CheckBox
        checkbox_gse_pt_3               matlab.ui.control.CheckBox
        checkbox_gse_pt_8               matlab.ui.control.CheckBox
        checkbox_gse_pt_7               matlab.ui.control.CheckBox
        checkbox_gse_pt_6               matlab.ui.control.CheckBox
        checkbox_gse_pt_5               matlab.ui.control.CheckBox
        CheckBox_17                     matlab.ui.control.CheckBox
        CheckBox_18                     matlab.ui.control.CheckBox
        CheckBox_19                     matlab.ui.control.CheckBox
        CheckBox_20                     matlab.ui.control.CheckBox
        CheckBox_21                     matlab.ui.control.CheckBox
        CheckBox_22                     matlab.ui.control.CheckBox
        CheckBox_23                     matlab.ui.control.CheckBox
        CheckBox_24                     matlab.ui.control.CheckBox
        CheckBox_25                     matlab.ui.control.CheckBox
        CheckBox_26                     matlab.ui.control.CheckBox
        CheckBox_27                     matlab.ui.control.CheckBox
        CheckBox_28                     matlab.ui.control.CheckBox
        CheckBox_29                     matlab.ui.control.CheckBox
        CheckBox_30                     matlab.ui.control.CheckBox
        CheckBox_31                     matlab.ui.control.CheckBox
        CheckBox_32                     matlab.ui.control.CheckBox
        LoadCellsLabel                  matlab.ui.control.Label
        NotimplementedyetLabel_2        matlab.ui.control.Label
        PressBoardPanel_2               matlab.ui.container.Panel
        PTsLabel_2                      matlab.ui.control.Label
        ValvesLabel_2                   matlab.ui.control.Label
        checkbox_ec_pt_1                matlab.ui.control.CheckBox
        checkbox_ec_pt_2                matlab.ui.control.CheckBox
        checkbox_ec_pt_4                matlab.ui.control.CheckBox
        checkbox_ec_pt_3                matlab.ui.control.CheckBox
        checkbox_ec_pt_8                matlab.ui.control.CheckBox
        checkbox_ec_pt_7                matlab.ui.control.CheckBox
        checkbox_ec_pt_6                matlab.ui.control.CheckBox
        checkbox_ec_pt_5                matlab.ui.control.CheckBox
        LoadCellsLabel_2                matlab.ui.control.Label
        checkbox_ec_vlv_0               matlab.ui.control.CheckBox
        checkbox_ec_vlv_1               matlab.ui.control.CheckBox
        checkbox_ec_vlv_3               matlab.ui.control.CheckBox
        checkbox_ec_vlv_2               matlab.ui.control.CheckBox
        checkbox_ec_vlv_7               matlab.ui.control.CheckBox
        checkbox_ec_vlv_6               matlab.ui.control.CheckBox
        checkbox_ec_vlv_5               matlab.ui.control.CheckBox
        checkbox_ec_vlv_4               matlab.ui.control.CheckBox
        checkbox_ec_lc_0                matlab.ui.control.CheckBox
        checkbox_ec_lc_1                matlab.ui.control.CheckBox
        checkbox_ec_lc_3                matlab.ui.control.CheckBox
        checkbox_ec_lc_2                matlab.ui.control.CheckBox
        checkbox_ec_lc_4                matlab.ui.control.CheckBox
        checkbox_ec_pt_0                matlab.ui.control.CheckBox
        checkbox_ec_pt_48               matlab.ui.control.CheckBox
        checkbox_ec_pt_49               matlab.ui.control.CheckBox
        checkbox_ec_vlv_8               matlab.ui.control.CheckBox
        checkbox_ec_vlv_9               matlab.ui.control.CheckBox
        checkbox_ec_vlv_10              matlab.ui.control.CheckBox
        checkbox_ec_vlv_11              matlab.ui.control.CheckBox
        checkbox_ec_vlv_12              matlab.ui.control.CheckBox
        checkbox_ec_pt_55               matlab.ui.control.CheckBox
        checkbox_ec_pt_56               matlab.ui.control.CheckBox
        checkbox_ec_pt_57               matlab.ui.control.CheckBox
        checkbox_ec_pt_58               matlab.ui.control.CheckBox
        checkbox_ec_pt_59               matlab.ui.control.CheckBox
        checkbox_ec_pt_60               matlab.ui.control.CheckBox
        checkbox_ec_pt_61               matlab.ui.control.CheckBox
        checkbox_ec_pt_62               matlab.ui.control.CheckBox
        checkbox_ec_pt_63               matlab.ui.control.CheckBox
        checkbox_ec_pt_64               matlab.ui.control.CheckBox
        checkbox_ec_pt_65               matlab.ui.control.CheckBox
        checkbox_ec_pt_66               matlab.ui.control.CheckBox
        NotimplementedyetLabel          matlab.ui.control.Label
        PostprocessingTab               matlab.ui.container.Tab
        ColorSchemeDropDownLabel        matlab.ui.control.Label
        ColorSchemeDropDown             matlab.ui.control.DropDown
        LineThicknessEditFieldLabel     matlab.ui.control.Label
        LineThicknessEditField          matlab.ui.control.EditField
        LineSymbolsEditFieldLabel       matlab.ui.control.Label
        LineSymbolsEditField            matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        xlabelEditFieldLabel            matlab.ui.control.Label
        xlabelEditField                 matlab.ui.control.EditField
        ylabelEditFieldLabel            matlab.ui.control.Label
        ylabelEditField                 matlab.ui.control.EditField
        ylimlowerlimitEditFieldLabel    matlab.ui.control.Label
        ylimlowerlimitEditField         matlab.ui.control.EditField
        ylimupperlimitEditFieldLabel    matlab.ui.control.Label
        ylimupperlimitEditField         matlab.ui.control.EditField
        xlimlowerlimitEditFieldLabel    matlab.ui.control.Label
        xlimlowerlimitEditField         matlab.ui.control.EditField
        xlimupperlimitEditFieldLabel    matlab.ui.control.Label
        xlimupperlimitEditField         matlab.ui.control.EditField
        ResetAxisLimitsButton           matlab.ui.control.Button
        SettingsTab                     matlab.ui.container.Tab
        ECPTprefixEditFieldLabel        matlab.ui.control.Label
        ECPTprefixEditField             matlab.ui.control.EditField
        GSEControllerPTprefixEditFieldLabel  matlab.ui.control.Label
        GSEControllerPTprefixEditField  matlab.ui.control.EditField
        GSEControllerPTsuffixEditFieldLabel  matlab.ui.control.Label
        GSEControllerPTsuffixEditField  matlab.ui.control.EditField
        ECPTsuffixEditFieldLabel        matlab.ui.control.Label
        ECPTsuffixEditField             matlab.ui.control.EditField
        ECValvesuffixEditFieldLabel     matlab.ui.control.Label
        ECValvesuffixEditField          matlab.ui.control.EditField
        ECValveprefixEditFieldLabel     matlab.ui.control.Label
        ECValveprefixEditField          matlab.ui.control.EditField
        GSEControllerValvesuffixEditFieldLabel  matlab.ui.control.Label
        GSEControllerValvesuffixEditField  matlab.ui.control.EditField
        GSEControllerValveprefixEditFieldLabel  matlab.ui.control.Label
        GSEControllerValveprefixEditField  matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startApp(app)
            clear global;
            close all;
            
            % Create options parameters
            global options;
            options.num_figures = 0;
            options.figure = '';
            
            % Prefixes and Suffixes
            options.engine_controller.pts.prefix = 'press_pressure_';
            options.engine_controller.pts.suffix = '__psi_';

            options.engine_controller.valves.prefix = 'press_vlv';
            options.engine_controller.valves.suffix = '_en_ul_';

            options.gse_controller.pts.prefix = 'gse_pressure_';
            options.gse_controller.pts.suffix = '__psi_';

            options.gse_controller.valves.prefix = 'gse_vlv';
            options.gse_controller.valves.suffix = '_en_ul_';
            
            % Create inputs parameters
            global inputs;
            inputs.engine_controller.pts.name = {};
            inputs.engine_controller.pts.number = [];
            
            inputs.engine_controller.valves.name = {};
            inputs.engine_controller.valves.number = [];
            
            inputs.gse_controller.pts.name = {};
            inputs.gse_controller.pts.number = [];
            
            inputs.gse_controller.valves.name = {};
            inputs.gse_controller.valves.number = [];

        end

        % Callback function
        function BoardSelected(app, event)
            value = app.SelectedBoardDropDown.Value;
            
            switch value
                case "Select Board"
                    app.GSEControllerPanel_2.Visible = 0;
                    app.PressBoardPanel.Visible = 0;
                case "GSE Controller"
                    app.GSEControllerPanel_2.Visible = 1;
                    app.PressBoardPanel.Visible = 0;
                case "Engine Controller"
                    app.GSEControllerPanel_2.Visible = 0;
                    app.PressBoardPanel.Visible = 1;
            end
        end

        % Callback function
        function FigureNames(app, event)
            global options
            
            value = app.FigureNamesTextArea.Value;
%             app.SelectFigureDropDown.Items{2:end} = value;
            
            for i = 1:length(value)
                app.SelectFigureDropDown.Items{i+1} = value{i};
                options.figures(i) = value(i);
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global options
            global inputs
                        
            % Read in data
            options.data = readtable(app.FileNameEditField.Value);
            options.data_length = length(table2array(options.data(:,1)));

            % Convert inputs to options
            options.engine_controller.pts.name = inputs.engine_controller.pts.name;
            options.engine_controller.pts.number = inputs.engine_controller.pts.number;
            
            options.engine_controller.valves.name = inputs.engine_controller.valves.name;
            options.engine_controller.valves.number = inputs.engine_controller.valves.number;
            
            options.gse_controller.pts.name = inputs.gse_controller.pts.name;
            options.gse_controller.pts.number = inputs.gse_controller.pts.number;
            
            options.gse_controller.valves.name = inputs.gse_controller.valves.name;
            options.gse_controller.valves.number = inputs.gse_controller.valves.number;

            %%%%%%%%%%%%%%% Engine Controller PTs %%%%%%%%%%%%%%%%
            % Implementation
            figure_legend = findobj(gcf, 'Type', 'Legend');
            line_objs = findobj(gca, 'Type', 'line');
            line_objs = flip(line_objs);
            
            % if legend has not been created yet
            if isempty(figure_legend)
                for i = 1:length(options.engine_controller.pts.number)
                    data = table2array(options.data(:,string(options.engine_controller.pts.prefix+string(num2str(options.engine_controller.pts.number(i)))+options.engine_controller.pts.suffix)));
                    hold(gca, 'on');
                    plot(gca, 1:options.data_length, data,'displayname',options.engine_controller.pts.name{i});
                end            
            % Check to see if all legend entries are in the pts entries
            % If legend entry exists but is not in pts entries, delete
            else
                figure_legend_strings = figure_legend.String;
                for i = 1:length(figure_legend.String)
                    all_board_channel_names = [options.engine_controller.pts.name, options.engine_controller.valves.name, options.gse_controller.pts.name, options.gse_controller.valves.name];
                    if ~any(ismember(all_board_channel_names, figure_legend_strings{i}))
                        delete(line_objs(i));
                    end
                end
            
            % Check to see if all pts entries are in the legend entries
            % If pts entry exists but is not in legend entries, plot
                for i = 1:length(options.engine_controller.pts.name)
                    if ~any(ismember(figure_legend.String, options.engine_controller.pts.name{i}))
                        data = table2array(options.data(:,string(options.engine_controller.pts.prefix+string(num2str(options.engine_controller.pts.number(i)))+options.engine_controller.pts.suffix)));
                        hold(gca, 'on');
                        plot(gca, 1:options.data_length, data,'displayname',options.engine_controller.pts.name{i});
                    end
                end
            end
            
            %%%%%%%%%%%%%%% Engine Controller Valves %%%%%%%%%%%%%%%%
            % Implementation
            figure_legend = findobj(gcf, 'Type', 'Legend');
            line_objs = findobj(gca, 'Type', 'line');
            line_objs = flip(line_objs);

            % if legend has not been created yet
            if isempty(figure_legend)
                for i = 1:length(options.engine_controller.valves.number)
                    data = table2array(options.data(:,string(options.engine_controller.valves.prefix+string(num2str(options.engine_controller.valves.number(i)))+options.engine_controller.valves.suffix)));
                    hold(gca, 'on');
                    plot(gca, 1:options.data_length, data,'displayname',options.engine_controller.valves.name{i});
                end            
            % Check to see if all legend entries are in the pts entries
            % If legend entry exists but is not in pts entries, delete
            else
                figure_legend_strings = figure_legend.String;
                for i = 1:length(figure_legend.String)
                    all_board_channel_names = [options.engine_controller.pts.name, options.engine_controller.valves.name, options.gse_controller.pts.name, options.gse_controller.valves.name];
                    if ~any(ismember(all_board_channel_names, figure_legend_strings{i}))
                        delete(line_objs(i));
                    end
                end
            
            % Check to see if all pts entries are in the legend entries
            % If pts entry exists but is not in legend entries, plot
                for i = 1:length(options.engine_controller.valves.name)
                    if ~any(ismember(figure_legend.String, options.engine_controller.valves.name{i}))
                        data = table2array(options.data(:,string(options.engine_controller.valves.prefix+string(num2str(options.engine_controller.valves.number(i)))+options.engine_controller.valves.suffix)));
                        hold(gca, 'on');
                        plot(gca, 1:options.data_length, data,'displayname',options.engine_controller.valves.name{i});
                    end
                end
            end
            
            %%%%%%%%%%%%%%% GSE Controller PTs %%%%%%%%%%%%%%%%
            % Implementation
            figure_legend = findobj(gcf, 'Type', 'Legend');
            line_objs = findobj(gca, 'Type', 'line');
            line_objs = flip(line_objs);
            
            % if legend has not been created yet
            if isempty(figure_legend)
                for i = 1:length(options.gse_controller.pts.number)
                    data = table2array(options.data(:,string(options.gse_controller.pts.prefix+string(num2str(options.gse_controller.pts.number(i)))+options.gse_controller.pts.suffix)));
                    hold(gca, 'on');
                    plot(gca, 1:options.data_length, data,'displayname',options.gse_controller.pts.name{i});
                end            
            % Check to see if all legend entries are in the pts entries
            % If legend entry exists but is not in pts entries, delete
            else
                figure_legend_strings = figure_legend.String;
                for i = 1:length(figure_legend.String)
                    all_board_channel_names = [options.engine_controller.pts.name, options.engine_controller.valves.name, options.gse_controller.pts.name, options.gse_controller.valves.name];
                    if ~any(ismember(all_board_channel_names, figure_legend_strings{i}))
                        delete(line_objs(i));
                    end
                end
            
            % Check to see if all pts entries are in the legend entries
            % If pts entry exists but is not in legend entries, plot
                for i = 1:length(options.gse_controller.pts.name)
                    if ~any(ismember(figure_legend.String, options.gse_controller.pts.name{i}))
                        data = table2array(options.data(:,string(options.gse_controller.pts.prefix+string(num2str(options.gse_controller.pts.number(i)))+options.gse_controller.pts.suffix)));
                        hold(gca, 'on');
                        plot(gca, 1:options.data_length, data,'displayname',options.gse_controller.pts.name{i});
                    end
                end
            end
            
            legend(gca);
        end

        % Value changed function: ec_pts_channels_text_area
        function EC_PT_Text_Changed(app, event)
            value = app.ec_pts_channels_text_area.Value;
            global inputs;
            inputs.engine_controller.pts.number = [];
            inputs.engine_controller.pts.name = {};
            for i = 1:length(value)
                row = strsplit(value{i});
                inputs.engine_controller.pts.number(i) = str2double(row(1));
                inputs.engine_controller.pts.name(i) = {[row{2:end}]};
            end
        end

        % Button pushed function: SavePlotsButton
        function savePlots(app, event)
            if exist('Plots', 'dir')
                rmdir('Plots', 's')
            end
            
            mkdir('Plots');
            mkdir('Plots/fig');
            mkdir('Plots/png');
            mkdir('Plots/pdf');
                        
            allAxes = findall(groot,'Type','Axes');
            for i = 1 : length(allAxes)
                fignew = figure('Visible','off'); % Invisible figure
                newAxes = copyobj(allAxes(i),fignew); % Copy the appropriate axes
                set(newAxes,'Position',get(groot,'DefaultAxesPosition')); % The original position is copied too, so adjust it.
                set(fignew,'CreateFcn','set(gcbf,''Visible'',''on'')'); % Make it visible upon loading
                legend(newAxes);
                
                filename = join(fignew.CurrentAxes.Title.String);
                fignew.CurrentAxes.Title.Visible = 'off';
                filename = regexprep(filename, ' ', '_');      % replaces spaces with underscores
                filename = regexprep(filename, '[.]', '');      % replaces periods with nothing
                savefig(fignew,string(join(['Plots/fig/' filename], '')),'compact'); 
                print(fignew,string(join(['Plots/png/', filename, '.png'],'')),'-dpng'); 
                set(fignew,'Units','Inches');
                pos = get(fignew,'Position');
                set(fignew,'PaperPositionMode','Auto','PaperUnits','Inches','PaperSize',[pos(3), pos(4)])
                print(fignew,string(join(['Plots/pdf/', filename, '.pdf'],'')),'-dpdf');
                close(fignew);
            end

        end

        % Button pushed function: CopyplotsButton
        function copyPlots(app, event)
            allAxes = findall(groot,'Type','Axes');
            for i = 1 : length(allAxes)
                fignew = figure('Visible','off'); % Invisible figure
                newAxes = copyobj(allAxes(i),fignew); % Copy the appropriate axes
                set(newAxes,'Position',get(groot,'DefaultAxesPosition')); % The original position is copied too, so adjust it.
                set(fignew,'Visible','on'); % Make it visible upon loading
                legend(newAxes);
            end
        end

        % Button pushed function: CloseAllFiguresButton
        function closeall(app, event)
            close all;
            
            global options
            options.figure = '';
            
            app.ListBox.Items = {'Select Figure'};
        end

        % Value changed function: ec_valve_channels_text_area
        function EC_Valve_Text_Changed(app, event)
            value = app.ec_valve_channels_text_area.Value;
            global inputs;
            inputs.engine_controller.valves.number = [];
            inputs.engine_controller.valves.name = {};
            for i = 1:length(value)
                row = strsplit(value{i});
                inputs.engine_controller.valves.number(i) = str2double(row(1));
                inputs.engine_controller.valves.name(i) = {[row{2:end}]};
            end

        end

        % Value changed function: ECPTprefixEditField
        function ECPTprefixEditFieldValueChanged(app, event)
            value = app.ECPTprefixEditField.Value;
            global options;
            options.engine_controller.pts.prefix = value;
        end

        % Value changed function: ECPTsuffixEditField
        function ECPTsuffixEditFieldValueChanged(app, event)
            value = app.ECPTsuffixEditField.Value;
            global options;
            options.engine_controller.pts.suffix = value;
        end

        % Callback function
        function SelectFigureDropDown_3ValueChanged(app, event)
            
        end

        % Value changing function: TitleEditField
        function TitleEditFieldValueChanging(app, event)
            changingValue = event.Value;
            title(gca, changingValue);
        end

        % Callback function
        function SelectFigureDropDownValueChanged(app, event)
            value = app.SelectFigureDropDown.Value;
            
            global options;
            index = find(contains(options.figures, value));
            set(0, 'CurrentFigure', index);
            
            options.figure = value;
        end

        % Callback function
        function numberOfFiguresChanged(app, event)
            value = app.NumberofFiguresEditField.Value;
            
            global options;
            options.num_figures = value;
        end

        % Value changed function: checkbox_gse_pt_1
        function checkbox_gse_pt_1ValueChanged(app, event)
            value = app.checkbox_gse_pt_1.Value;
            
            axesObjs = get(gcf, 'Children');
            lineObjs = findobj(axesObjs, 'type', 'line');
            
            global options
            index = find(options.engine_controller.pts.number==1);
            lineObjs = flip(lineObjs);
            
            if value
                set(lineObjs(index), 'visible', 'on');
            else
                set(lineObjs(index), 'visible', 'off');
            end
        end

        % Value changed function: EnterFigureNameEditField
        function EnterFigureNameEditFieldValueChanged(app, event)
            value = app.EnterFigureNameEditField.Value;
            global options
                        
            app.ListBox.Items{end+1} = value;
            if app.PlotButton.Visible == 0
                app.PlotButton.Visible = 1;
                app.SavePlotsButton.Visible = 1;
                app.CopyplotsButton.Visible = 1;
                app.CloseAllFiguresButton.Visible = 1;
            end
            
            % Create new figure
            options.figures(1 + options.num_figures) = {value};
            options.num_figures = options.num_figures + 1;
            options.figure = value;
            figure('Name', char(options.figures(end)), 'Position', [920 480 560 420]);
            
            % Update List Box to new figure
            app.ListBox.Value = value;
            app.ListBoxValueChanged;

            % Wipe New Figure Edit Field
            app.EnterFigureNameEditField.Value = '';
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
            global options;
            index = find(contains(options.figures, value));
            set(0, 'CurrentFigure', index);
            
            options.figure = value;
        end

        % Button pushed function: CloseFigureButton
        function closeCurrentFigure(app, event)
            global options;
            options.figure = '';
            options.num_figures = options.num_figures - 1;
            
            % Use fig = findobj(gcf); fig(1).Name; index =
            % find(ismember(A1, 'hello')) to get index of figure name from
            % list box index
            
            fig = findobj(gcf);
            fig_name = fig(1).Name;
            index = find(ismember(app.ListBox.Items, fig_name));
            app.ListBox.Items(index) = [];
            
            close(gcf);
            
            app.ListBox.Value = app.ListBox.Items(end);
            app.ListBoxValueChanged;
        end

        % Value changing function: EnterFigureNameEditField
        function EnterFigureNameEditFieldValueChanging(app, event)
            
        end

        % Button pushed function: ClearFigureButton
        function ClearFigureButtonPushed(app, event)
            clf;
        end

        % Value changed function: checkbox_ec_pt_1
        function checkbox_ec_pt_1ValueChanged(app, event)
            value = app.checkbox_ec_pt_1.Value;
            
            axesObjs = get(gcf, 'Children');
            lineObjs = findobj(axesObjs, 'type', 'line');
            
            global options
            index = find(options.engine_controller.pts.number==1);
            lineObjs = flip(lineObjs);
            
            if value
                set(lineObjs(index), 'visible', 'on');
            else
                set(lineObjs(index), 'visible', 'off');
            end
        end

        % Value changed function: checkbox_ec_pt_2
        function checkbox_ec_pt_2ValueChanged(app, event)
            value = app.checkbox_ec_pt_2.Value;
                        
            axesObjs = get(gcf, 'Children');
            lineObjs = findobj(axesObjs, 'type', 'line');
            
            global options
            index = find(options.engine_controller.pts.number==2);
            lineObjs = flip(lineObjs);
            
            if value
                set(lineObjs(index), 'visible', 'on');
            else
                set(lineObjs(index), 'visible', 'off');
            end
        end

        % Value changed function: checkbox_ec_pt_3
        function checkbox_ec_pt_3ValueChanged(app, event)
            value = app.checkbox_ec_pt_3.Value;
                                    
            axesObjs = get(gcf, 'Children');
            lineObjs = findobj(axesObjs, 'type', 'line');
            
            global options
            index = find(options.engine_controller.pts.number==3);
            lineObjs = flip(lineObjs);
            
            if value
                set(lineObjs(index), 'visible', 'on');
            else
                set(lineObjs(index), 'visible', 'off');
            end
        end

        % Value changed function: checkbox_ec_pt_4
        function checkbox_ec_pt_4ValueChanged(app, event)
            value = app.checkbox_ec_pt_4.Value;
                                    
            axesObjs = get(gcf, 'Children');
            lineObjs = findobj(axesObjs, 'type', 'line');
            
            global options
            index = find(options.engine_controller.pts.number==4);
            lineObjs = flip(lineObjs);
            
            if value
                set(lineObjs(index), 'visible', 'on');
            else
                set(lineObjs(index), 'visible', 'off');
            end
        end

        % Value changed function: checkbox_ec_pt_5
        function checkbox_ec_pt_5ValueChanged(app, event)
            value = app.checkbox_ec_pt_5.Value;
                                    
            axesObjs = get(gcf, 'Children');
            lineObjs = findobj(axesObjs, 'type', 'line');
            
            global options
            index = find(options.engine_controller.pts.number==5);
            lineObjs = flip(lineObjs);
            
            if value
                set(lineObjs(index), 'visible', 'on');
            else
                set(lineObjs(index), 'visible', 'off');
            end
        end

        % Value changed function: checkbox_ec_pt_6
        function checkbox_ec_pt_6ValueChanged(app, event)
            value = app.checkbox_ec_pt_6.Value;
                                    
            axesObjs = get(gcf, 'Children');
            lineObjs = findobj(axesObjs, 'type', 'line');
            
            global options
            index = find(options.engine_controller.pts.number==6);
            lineObjs = flip(lineObjs);
            
            if value
                set(lineObjs(index), 'visible', 'on');
            else
                set(lineObjs(index), 'visible', 'off');
            end
        end

        % Value changed function: checkbox_ec_pt_7
        function checkbox_ec_pt_7ValueChanged(app, event)
            value = app.checkbox_ec_pt_7.Value;
                                    
            axesObjs = get(gcf, 'Children');
            lineObjs = findobj(axesObjs, 'type', 'line');
            
            global options
            index = find(options.engine_controller.pts.number==7);
            lineObjs = flip(lineObjs);
            
            if value
                set(lineObjs(index), 'visible', 'on');
            else
                set(lineObjs(index), 'visible', 'off');
            end
        end

        % Value changed function: checkbox_ec_pt_8
        function checkbox_ec_pt_8ValueChanged(app, event)
            value = app.checkbox_ec_pt_8.Value;
                                    
            axesObjs = get(gcf, 'Children');
            lineObjs = findobj(axesObjs, 'type', 'line');
            
            global options
            index = find(options.engine_controller.pts.number==8);
            lineObjs = flip(lineObjs);
            
            if value
                set(lineObjs(index), 'visible', 'on');
            else
                set(lineObjs(index), 'visible', 'off');
            end
        end

        % Value changed function: checkbox_ec_pt_0
        function checkbox_ec_pt_0ValueChanged(app, event)
            value = app.checkbox_ec_pt_0.Value;
                                    
            axesObjs = get(gcf, 'Children');
            lineObjs = findobj(axesObjs, 'type', 'line');
            
            global options
            index = find(options.engine_controller.pts.number==0);
            lineObjs = flip(lineObjs);
            
            if value
                set(lineObjs(index), 'visible', 'on');
            else
                set(lineObjs(index), 'visible', 'off');
            end
        end

        % Value changed function: gse_pts_channels_text_area
        function gse_pts_channels_text_areaValueChanged(app, event)
            value = app.gse_pts_channels_text_area.Value;
            global inputs;
            inputs.gse_controller.pts.number = [];
            inputs.gse_controller.pts.name = {};
            for i = 1:length(value)
                row = strsplit(value{i});
                inputs.gse_controller.pts.number(i) = str2double(row(1));
                inputs.gse_controller.pts.name(i) = {[row{2:end}]};
            end
        end

        % Value changed function: gse_valve_channels_text_area
        function gse_valve_channels_text_areaValueChanged(app, event)
            value = app.gse_valve_channels_text_area.Value;
            global inputs;
            inputs.gse_controller.valves.number = [];
            inputs.gse_controller.valves.name = {};
            for i = 1:length(value)
                row = strsplit(value{i});
                inputs.gse_controller.valves.number(i) = str2double(row(1));
                inputs.gse_controller.valves.name(i) = {[row{2:end}]};
            end
        end

        % Value changed function: ECValveprefixEditField
        function ECValveprefixEditFieldValueChanged(app, event)
            value = app.ECValveprefixEditField.Value;
            global options;
            options.engine_controller.valves.prefix = value;
        end

        % Value changed function: ECValvesuffixEditField
        function ECValvesuffixEditFieldValueChanged(app, event)
            value = app.ECValvesuffixEditField.Value;
            global options;
            options.engine_controller.valves.suffix = value;

        end

        % Value changed function: GSEControllerPTprefixEditField
        function GSEControllerPTprefixEditFieldValueChanged(app, event)
            value = app.GSEControllerPTprefixEditField.Value;
            global options;
            options.gse_controller.pts.prefix = value;
        end

        % Value changed function: GSEControllerPTsuffixEditField
        function GSEControllerPTsuffixEditFieldValueChanged(app, event)
            value = app.GSEControllerPTsuffixEditField.Value;
            global options;
            options.gse_controller.valves.suffix = value;
        end

        % Value changed function: GSEControllerValveprefixEditField
        function GSEControllerValveprefixEditFieldValueChanged(app, event)
            value = app.GSEControllerValveprefixEditField.Value;
            global options;
            options.gse_controller.valves.prefix = value;
        end

        % Value changed function: GSEControllerValvesuffixEditField
        function GSEControllerValvesuffixEditFieldValueChanged(app, event)
            value = app.GSEControllerValvesuffixEditField.Value;
            global options;
            options.gse_controller.valves.suffix = value;
        end

        % Value changing function: xlabelEditField
        function xlabelEditFieldValueChanging(app, event)
            changingValue = event.Value;
            xlabel(gca, changingValue);
        end

        % Value changing function: ylabelEditField
        function ylabelEditFieldValueChanging(app, event)
            changingValue = event.Value;
            ylabel(gca, changingValue);
        end

        % Value changed function: ylimupperlimitEditField
        function ylimupperlimitEditFieldValueChanged(app, event)
%             value = app.ylimupperlimitEditField.Value;
%             limsy=get(gca,'ylim');
%             set(gca,'ylim',[limsy(1) str2double(value)]);
        end

        % Value changed function: ylimlowerlimitEditField
        function ylimlowerlimitEditFieldValueChanged(app, event)
%             value = app.ylimlowerlimitEditField.Value;
%             limsy=get(gca,'YLim');
%             set(gca,'Ylim',[str2double(value) limsy(2)]);
        end

        % Value changed function: xlimupperlimitEditField
        function xlimupperlimitEditFieldValueChanged(app, event)
%             value = app.xlimupperlimitEditField.Value;
%             limsx=get(gca,'XLim');
%             set(gca,'Xlim',[limsx(1) str2double(value)]);
        end

        % Value changed function: xlimlowerlimitEditField
        function xlimlowerlimitEditFieldValueChanged(app, event)
%             value = app.xlimlowerlimitEditField.Value;
%             limsx=get(gca,'XLim');
%             set(gca,'Xlim',[str2double(value) limsx(2)]);
        end

        % Value changing function: ylimupperlimitEditField
        function ylimupperlimitEditFieldValueChanging(app, event)
            changingValue = event.Value;
            limsy=get(gca,'ylim');
            set(gca,'ylim',[limsy(1) str2double(changingValue)]);
        end

        % Button pushed function: ResetAxisLimitsButton
        function ResetAxisLimitsButtonPushed(app, event)
            axis auto;
        end

        % Value changing function: ylimlowerlimitEditField
        function ylimlowerlimitEditFieldValueChanging(app, event)
            changingValue = event.Value;
            limsy=get(gca,'YLim');
            set(gca,'Ylim',[str2double(changingValue) limsy(2)]);
        end

        % Value changing function: xlimupperlimitEditField
        function xlimupperlimitEditFieldValueChanging(app, event)
            changingValue = event.Value;
            limsx=get(gca,'XLim');
            set(gca,'Xlim',[limsx(1) str2double(changingValue)]);
        end

        % Value changing function: xlimlowerlimitEditField
        function xlimlowerlimitEditFieldValueChanging(app, event)
            changingValue = event.Value;
            limsx=get(gca,'XLim');
            set(gca,'Xlim',[str2double(changingValue) limsx(2)]);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            close all;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.FileNameEditFieldLabel = uilabel(app.LoadDataTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.LoadDataTab, 'text');
            app.FileNameEditField.Value = 'test_log';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.PlotButton = uibutton(app.PlotsTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SavePlotsButton = uibutton(app.PlotsTab, 'push');
            app.SavePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @savePlots, true);
            app.SavePlotsButton.Text = 'Save Plots';
            app.CopyplotsButton = uibutton(app.PlotsTab, 'push');
            app.CopyplotsButton.ButtonPushedFcn = createCallbackFcn(app, @copyPlots, true);
            app.CopyplotsButton.Text = 'Copy plots';
            app.CloseAllFiguresButton = uibutton(app.PlotsTab, 'push');
            app.CloseAllFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @closeall, true);
            app.CloseAllFiguresButton.Text = 'Close All Figures';
            app.EnterFigureNameEditFieldLabel = uilabel(app.PlotsTab);
            app.EnterFigureNameEditFieldLabel.Text = 'Enter Figure Name:';
            app.EnterFigureNameEditField = uieditfield(app.PlotsTab, 'text');
            app.EnterFigureNameEditField.ValueChangedFcn = createCallbackFcn(app, @EnterFigureNameEditFieldValueChanged, true);
            app.EnterFigureNameEditField.ValueChangingFcn = createCallbackFcn(app, @EnterFigureNameEditFieldValueChanging, true);
            app.ListBox = uilistbox(app.PlotsTab);
            app.ListBox.Items = {'Select Figure'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Select Figure';
            app.CloseFigureButton = uibutton(app.PlotsTab, 'push');
            app.CloseFigureButton.ButtonPushedFcn = createCallbackFcn(app, @closeCurrentFigure, true);
            app.CloseFigureButton.Text = 'Close Figure';
            app.ClearFigureButton = uibutton(app.PlotsTab, 'push');
            app.ClearFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFigureButtonPushed, true);
            app.ClearFigureButton.Text = 'Clear Figure';
            app.GSEControllerPanel_2 = uipanel(app.PlotsTab);
            app.GSEControllerPanel_2.Title = 'GSE Controller';
            app.gse_valve_channels_text_area = uitextarea(app.GSEControllerPanel_2);
            app.gse_valve_channels_text_area.ValueChangedFcn = createCallbackFcn(app, @gse_valve_channels_text_areaValueChanged, true);
            app.ValveChannelsTextAreaLabel = uilabel(app.GSEControllerPanel_2);
            app.ValveChannelsTextAreaLabel.Text = 'Valve Channels';
            app.gse_pts_channels_text_area = uitextarea(app.GSEControllerPanel_2);
            app.gse_pts_channels_text_area.ValueChangedFcn = createCallbackFcn(app, @gse_pts_channels_text_areaValueChanged, true);
            app.PTChannelsTextAreaLabel = uilabel(app.GSEControllerPanel_2);
            app.PTChannelsTextAreaLabel.Text = 'PT Channels';
            app.PressBoardPanel = uipanel(app.PlotsTab);
            app.PressBoardPanel.Title = 'Press Board';
            app.PTChannelsTextArea_2Label = uilabel(app.PressBoardPanel);
            app.PTChannelsTextArea_2Label.Text = 'PT Channels';
            app.ec_pts_channels_text_area = uitextarea(app.PressBoardPanel);
            app.ec_pts_channels_text_area.ValueChangedFcn = createCallbackFcn(app, @EC_PT_Text_Changed, true);
            app.ec_valve_channels_text_area = uitextarea(app.PressBoardPanel);
            app.ec_valve_channels_text_area.ValueChangedFcn = createCallbackFcn(app, @EC_Valve_Text_Changed, true);
            app.ValveChannelsTextArea_2Label = uilabel(app.PressBoardPanel);
            app.ValveChannelsTextArea_2Label.Text = 'Valve Channels';
            app.GSEControllerPanel = uipanel(app.PlotsTab);
            app.GSEControllerPanel.Title = 'GSE Controller';
            app.PTsLabel = uilabel(app.GSEControllerPanel);
            app.PTsLabel.Text = 'PTs';
            app.ValvesLabel = uilabel(app.GSEControllerPanel);
            app.ValvesLabel.Text = 'Valves';
            app.checkbox_gse_pt_1 = uicheckbox(app.GSEControllerPanel);
            app.checkbox_gse_pt_1.ValueChangedFcn = createCallbackFcn(app, @checkbox_gse_pt_1ValueChanged, true);
            app.checkbox_gse_pt_1.Visible = 'off';
            app.checkbox_gse_pt_1.Text = '1';
            app.checkbox_gse_pt_1.Value = true;
            app.checkbox_gse_pt_2 = uicheckbox(app.GSEControllerPanel);
            app.checkbox_gse_pt_2.Visible = 'off';
            app.checkbox_gse_pt_2.Text = '2';
            app.checkbox_gse_pt_2.Value = true;
            app.checkbox_gse_pt_4 = uicheckbox(app.GSEControllerPanel);
            app.checkbox_gse_pt_4.Visible = 'off';
            app.checkbox_gse_pt_4.Text = '4';
            app.checkbox_gse_pt_4.Value = true;
            app.checkbox_gse_pt_3 = uicheckbox(app.GSEControllerPanel);
            app.checkbox_gse_pt_3.Visible = 'off';
            app.checkbox_gse_pt_3.Text = '3';
            app.checkbox_gse_pt_3.Value = true;
            app.checkbox_gse_pt_8 = uicheckbox(app.GSEControllerPanel);
            app.checkbox_gse_pt_8.Visible = 'off';
            app.checkbox_gse_pt_8.Text = '8';
            app.checkbox_gse_pt_8.Value = true;
            app.checkbox_gse_pt_7 = uicheckbox(app.GSEControllerPanel);
            app.checkbox_gse_pt_7.Visible = 'off';
            app.checkbox_gse_pt_7.Text = '7';
            app.checkbox_gse_pt_7.Value = true;
            app.checkbox_gse_pt_6 = uicheckbox(app.GSEControllerPanel);
            app.checkbox_gse_pt_6.Visible = 'off';
            app.checkbox_gse_pt_6.Text = '6';
            app.checkbox_gse_pt_6.Value = true;
            app.checkbox_gse_pt_5 = uicheckbox(app.GSEControllerPanel);
            app.checkbox_gse_pt_5.Visible = 'off';
            app.checkbox_gse_pt_5.Text = '5';
            app.checkbox_gse_pt_5.Value = true;
            app.CheckBox_17 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_17.Visible = 'off';
            app.CheckBox_17.Text = '8';
            app.CheckBox_17.Value = true;
            app.CheckBox_18 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_18.Visible = 'off';
            app.CheckBox_18.Text = '7';
            app.CheckBox_18.Value = true;
            app.CheckBox_19 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_19.Visible = 'off';
            app.CheckBox_19.Text = '6';
            app.CheckBox_19.Value = true;
            app.CheckBox_20 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_20.Visible = 'off';
            app.CheckBox_20.Text = '5';
            app.CheckBox_20.Value = true;
            app.CheckBox_21 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_21.Visible = 'off';
            app.CheckBox_21.Text = '4';
            app.CheckBox_21.Value = true;
            app.CheckBox_22 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_22.Visible = 'off';
            app.CheckBox_22.Text = '3';
            app.CheckBox_22.Value = true;
            app.CheckBox_23 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_23.Visible = 'off';
            app.CheckBox_23.Text = '2';
            app.CheckBox_23.Value = true;
            app.CheckBox_24 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_24.Visible = 'off';
            app.CheckBox_24.Text = '1';
            app.CheckBox_24.Value = true;
            app.CheckBox_25 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_25.Visible = 'off';
            app.CheckBox_25.Text = '8';
            app.CheckBox_25.Value = true;
            app.CheckBox_26 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_26.Visible = 'off';
            app.CheckBox_26.Text = '7';
            app.CheckBox_26.Value = true;
            app.CheckBox_27 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_27.Visible = 'off';
            app.CheckBox_27.Text = '6';
            app.CheckBox_27.Value = true;
            app.CheckBox_28 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_28.Visible = 'off';
            app.CheckBox_28.Text = '5';
            app.CheckBox_28.Value = true;
            app.CheckBox_29 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_29.Visible = 'off';
            app.CheckBox_29.Text = '4';
            app.CheckBox_29.Value = true;
            app.CheckBox_30 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_30.Visible = 'off';
            app.CheckBox_30.Text = '3';
            app.CheckBox_30.Value = true;
            app.CheckBox_31 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_31.Visible = 'off';
            app.CheckBox_31.Text = '2';
            app.CheckBox_31.Value = true;
            app.CheckBox_32 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_32.Visible = 'off';
            app.CheckBox_32.Text = '1';
            app.CheckBox_32.Value = true;
            app.LoadCellsLabel = uilabel(app.GSEControllerPanel);
            app.LoadCellsLabel.Text = 'Load Cells';
            app.NotimplementedyetLabel_2 = uilabel(app.GSEControllerPanel);
            app.NotimplementedyetLabel_2.Text = 'Not implemented yet';
            app.PressBoardPanel_2 = uipanel(app.PlotsTab);
            app.PressBoardPanel_2.Title = 'Press Board';
            app.PTsLabel_2 = uilabel(app.PressBoardPanel_2);
            app.PTsLabel_2.Text = 'PTs';
            app.ValvesLabel_2 = uilabel(app.PressBoardPanel_2);
            app.ValvesLabel_2.Text = 'Valves';
            app.checkbox_ec_pt_1 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_1.ValueChangedFcn = createCallbackFcn(app, @checkbox_ec_pt_1ValueChanged, true);
            app.checkbox_ec_pt_1.Visible = 'off';
            app.checkbox_ec_pt_1.Text = '1';
            app.checkbox_ec_pt_1.Value = true;
            app.checkbox_ec_pt_2 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_2.ValueChangedFcn = createCallbackFcn(app, @checkbox_ec_pt_2ValueChanged, true);
            app.checkbox_ec_pt_2.Visible = 'off';
            app.checkbox_ec_pt_2.Text = '2';
            app.checkbox_ec_pt_2.Value = true;
            app.checkbox_ec_pt_4 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_4.ValueChangedFcn = createCallbackFcn(app, @checkbox_ec_pt_4ValueChanged, true);
            app.checkbox_ec_pt_4.Visible = 'off';
            app.checkbox_ec_pt_4.Text = '4';
            app.checkbox_ec_pt_4.Value = true;
            app.checkbox_ec_pt_3 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_3.ValueChangedFcn = createCallbackFcn(app, @checkbox_ec_pt_3ValueChanged, true);
            app.checkbox_ec_pt_3.Visible = 'off';
            app.checkbox_ec_pt_3.Text = '3';
            app.checkbox_ec_pt_3.Value = true;
            app.checkbox_ec_pt_8 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_8.ValueChangedFcn = createCallbackFcn(app, @checkbox_ec_pt_8ValueChanged, true);
            app.checkbox_ec_pt_8.Visible = 'off';
            app.checkbox_ec_pt_8.Text = '8';
            app.checkbox_ec_pt_8.Value = true;
            app.checkbox_ec_pt_7 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_7.ValueChangedFcn = createCallbackFcn(app, @checkbox_ec_pt_7ValueChanged, true);
            app.checkbox_ec_pt_7.Visible = 'off';
            app.checkbox_ec_pt_7.Text = '7';
            app.checkbox_ec_pt_7.Value = true;
            app.checkbox_ec_pt_6 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_6.ValueChangedFcn = createCallbackFcn(app, @checkbox_ec_pt_6ValueChanged, true);
            app.checkbox_ec_pt_6.Visible = 'off';
            app.checkbox_ec_pt_6.Text = '6';
            app.checkbox_ec_pt_6.Value = true;
            app.checkbox_ec_pt_5 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_5.ValueChangedFcn = createCallbackFcn(app, @checkbox_ec_pt_5ValueChanged, true);
            app.checkbox_ec_pt_5.Visible = 'off';
            app.checkbox_ec_pt_5.Text = '5';
            app.checkbox_ec_pt_5.Value = true;
            app.LoadCellsLabel_2 = uilabel(app.PressBoardPanel_2);
            app.LoadCellsLabel_2.Text = {'Load'; 'Cells'};
            app.checkbox_ec_vlv_0 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_vlv_0.Visible = 'off';
            app.checkbox_ec_vlv_0.Text = '0';
            app.checkbox_ec_vlv_0.Value = true;
            app.checkbox_ec_vlv_1 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_vlv_1.Visible = 'off';
            app.checkbox_ec_vlv_1.Text = '1';
            app.checkbox_ec_vlv_1.Value = true;
            app.checkbox_ec_vlv_3 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_vlv_3.Visible = 'off';
            app.checkbox_ec_vlv_3.Text = '3';
            app.checkbox_ec_vlv_3.Value = true;
            app.checkbox_ec_vlv_2 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_vlv_2.Visible = 'off';
            app.checkbox_ec_vlv_2.Text = '2';
            app.checkbox_ec_vlv_2.Value = true;
            app.checkbox_ec_vlv_7 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_vlv_7.Visible = 'off';
            app.checkbox_ec_vlv_7.Text = '7';
            app.checkbox_ec_vlv_7.Value = true;
            app.checkbox_ec_vlv_6 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_vlv_6.Visible = 'off';
            app.checkbox_ec_vlv_6.Text = '6';
            app.checkbox_ec_vlv_6.Value = true;
            app.checkbox_ec_vlv_5 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_vlv_5.Visible = 'off';
            app.checkbox_ec_vlv_5.Text = '5';
            app.checkbox_ec_vlv_5.Value = true;
            app.checkbox_ec_vlv_4 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_vlv_4.Visible = 'off';
            app.checkbox_ec_vlv_4.Text = '4';
            app.checkbox_ec_vlv_4.Value = true;
            app.checkbox_ec_lc_0 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_lc_0.Visible = 'off';
            app.checkbox_ec_lc_0.Text = '0';
            app.checkbox_ec_lc_0.Value = true;
            app.checkbox_ec_lc_1 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_lc_1.Visible = 'off';
            app.checkbox_ec_lc_1.Text = '1';
            app.checkbox_ec_lc_1.Value = true;
            app.checkbox_ec_lc_3 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_lc_3.Visible = 'off';
            app.checkbox_ec_lc_3.Text = '3';
            app.checkbox_ec_lc_3.Value = true;
            app.checkbox_ec_lc_2 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_lc_2.Visible = 'off';
            app.checkbox_ec_lc_2.Text = '2';
            app.checkbox_ec_lc_2.Value = true;
            app.checkbox_ec_lc_4 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_lc_4.Visible = 'off';
            app.checkbox_ec_lc_4.Text = '4';
            app.checkbox_ec_lc_4.Value = true;
            app.checkbox_ec_pt_0 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_0.ValueChangedFcn = createCallbackFcn(app, @checkbox_ec_pt_0ValueChanged, true);
            app.checkbox_ec_pt_0.Visible = 'off';
            app.checkbox_ec_pt_0.Text = '0';
            app.checkbox_ec_pt_0.Value = true;
            app.checkbox_ec_pt_48 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_48.Visible = 'off';
            app.checkbox_ec_pt_48.Text = '10';
            app.checkbox_ec_pt_48.Value = true;
            app.checkbox_ec_pt_49 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_49.Visible = 'off';
            app.checkbox_ec_pt_49.Text = '9';
            app.checkbox_ec_pt_49.Value = true;
            app.checkbox_ec_vlv_8 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_vlv_8.Visible = 'off';
            app.checkbox_ec_vlv_8.Text = '8';
            app.checkbox_ec_vlv_8.Value = true;
            app.checkbox_ec_vlv_9 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_vlv_9.Visible = 'off';
            app.checkbox_ec_vlv_9.Text = '9';
            app.checkbox_ec_vlv_9.Value = true;
            app.checkbox_ec_vlv_10 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_vlv_10.Visible = 'off';
            app.checkbox_ec_vlv_10.Text = '10';
            app.checkbox_ec_vlv_10.Value = true;
            app.checkbox_ec_vlv_11 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_vlv_11.Visible = 'off';
            app.checkbox_ec_vlv_11.Text = '11';
            app.checkbox_ec_vlv_11.Value = true;
            app.checkbox_ec_vlv_12 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_vlv_12.Visible = 'off';
            app.checkbox_ec_vlv_12.Text = '12';
            app.checkbox_ec_vlv_12.Value = true;
            app.checkbox_ec_pt_55 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_55.Visible = 'off';
            app.checkbox_ec_pt_55.Text = '10';
            app.checkbox_ec_pt_55.Value = true;
            app.checkbox_ec_pt_56 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_56.Visible = 'off';
            app.checkbox_ec_pt_56.Text = '9';
            app.checkbox_ec_pt_56.Value = true;
            app.checkbox_ec_pt_57 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_57.Visible = 'off';
            app.checkbox_ec_pt_57.Text = '8';
            app.checkbox_ec_pt_57.Value = true;
            app.checkbox_ec_pt_58 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_58.Visible = 'off';
            app.checkbox_ec_pt_58.Text = '7';
            app.checkbox_ec_pt_58.Value = true;
            app.checkbox_ec_pt_59 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_59.Visible = 'off';
            app.checkbox_ec_pt_59.Text = '6';
            app.checkbox_ec_pt_59.Value = true;
            app.checkbox_ec_pt_60 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_60.Visible = 'off';
            app.checkbox_ec_pt_60.Text = '5';
            app.checkbox_ec_pt_60.Value = true;
            app.checkbox_ec_pt_61 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_61.Visible = 'off';
            app.checkbox_ec_pt_61.Text = '4';
            app.checkbox_ec_pt_61.Value = true;
            app.checkbox_ec_pt_62 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_62.Visible = 'off';
            app.checkbox_ec_pt_62.Text = '3';
            app.checkbox_ec_pt_62.Value = true;
            app.checkbox_ec_pt_63 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_63.Visible = 'off';
            app.checkbox_ec_pt_63.Text = '2';
            app.checkbox_ec_pt_63.Value = true;
            app.checkbox_ec_pt_64 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_64.Visible = 'off';
            app.checkbox_ec_pt_64.Text = '1';
            app.checkbox_ec_pt_64.Value = true;
            app.checkbox_ec_pt_65 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_65.Visible = 'off';
            app.checkbox_ec_pt_65.Text = '0';
            app.checkbox_ec_pt_65.Value = true;
            app.checkbox_ec_pt_66 = uicheckbox(app.PressBoardPanel_2);
            app.checkbox_ec_pt_66.Visible = 'off';
            app.checkbox_ec_pt_66.Text = '11';
            app.checkbox_ec_pt_66.Value = true;
            app.NotimplementedyetLabel = uilabel(app.PressBoardPanel_2);
            app.NotimplementedyetLabel.Text = 'Not implemented yet';
            app.PostprocessingTab = uitab(app.TabGroup);
            app.PostprocessingTab.Title = 'Postprocessing';
            app.ColorSchemeDropDownLabel = uilabel(app.PostprocessingTab);
            app.ColorSchemeDropDownLabel.Visible = 'off';
            app.ColorSchemeDropDownLabel.Text = 'Color Scheme:';
            app.ColorSchemeDropDown = uidropdown(app.PostprocessingTab);
            app.ColorSchemeDropDown.Items = {'Select One', 'Jet', 'Colorful'};
            app.ColorSchemeDropDown.Visible = 'off';
            app.ColorSchemeDropDown.Value = 'Select One';
            app.LineThicknessEditFieldLabel = uilabel(app.PostprocessingTab);
            app.LineThicknessEditFieldLabel.Visible = 'off';
            app.LineThicknessEditFieldLabel.Text = 'Line Thickness:';
            app.LineThicknessEditField = uieditfield(app.PostprocessingTab, 'text');
            app.LineThicknessEditField.Visible = 'off';
            app.LineSymbolsEditFieldLabel = uilabel(app.PostprocessingTab);
            app.LineSymbolsEditFieldLabel.Visible = 'off';
            app.LineSymbolsEditFieldLabel.Text = 'Line Symbols:';
            app.LineSymbolsEditField = uieditfield(app.PostprocessingTab, 'text');
            app.LineSymbolsEditField.Visible = 'off';
            app.TitleEditFieldLabel = uilabel(app.PostprocessingTab);
            app.TitleEditFieldLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.PostprocessingTab, 'text');
            app.TitleEditField.ValueChangingFcn = createCallbackFcn(app, @TitleEditFieldValueChanging, true);
            app.xlabelEditFieldLabel = uilabel(app.PostprocessingTab);
            app.xlabelEditFieldLabel.Text = 'xlabel:';
            app.xlabelEditField = uieditfield(app.PostprocessingTab, 'text');
            app.xlabelEditField.ValueChangingFcn = createCallbackFcn(app, @xlabelEditFieldValueChanging, true);
            app.ylabelEditFieldLabel = uilabel(app.PostprocessingTab);
            app.ylabelEditFieldLabel.Text = 'ylabel:';
            app.ylabelEditField = uieditfield(app.PostprocessingTab, 'text');
            app.ylabelEditField.ValueChangingFcn = createCallbackFcn(app, @ylabelEditFieldValueChanging, true);
            app.ylimlowerlimitEditFieldLabel = uilabel(app.PostprocessingTab);
            app.ylimlowerlimitEditFieldLabel.Text = 'ylim lower limit:';
            app.ylimlowerlimitEditField = uieditfield(app.PostprocessingTab, 'text');
            app.ylimlowerlimitEditField.ValueChangedFcn = createCallbackFcn(app, @ylimlowerlimitEditFieldValueChanged, true);
            app.ylimlowerlimitEditField.ValueChangingFcn = createCallbackFcn(app, @ylimlowerlimitEditFieldValueChanging, true);
            app.ylimupperlimitEditFieldLabel = uilabel(app.PostprocessingTab);
            app.ylimupperlimitEditFieldLabel.Text = 'ylim upper limit:';
            app.ylimupperlimitEditField = uieditfield(app.PostprocessingTab, 'text');
            app.ylimupperlimitEditField.ValueChangedFcn = createCallbackFcn(app, @ylimupperlimitEditFieldValueChanged, true);
            app.ylimupperlimitEditField.ValueChangingFcn = createCallbackFcn(app, @ylimupperlimitEditFieldValueChanging, true);
            app.xlimlowerlimitEditFieldLabel = uilabel(app.PostprocessingTab);
            app.xlimlowerlimitEditFieldLabel.Text = 'xlim lower limit:';
            app.xlimlowerlimitEditField = uieditfield(app.PostprocessingTab, 'text');
            app.xlimlowerlimitEditField.ValueChangedFcn = createCallbackFcn(app, @xlimlowerlimitEditFieldValueChanged, true);
            app.xlimlowerlimitEditField.ValueChangingFcn = createCallbackFcn(app, @xlimlowerlimitEditFieldValueChanging, true);
            app.xlimupperlimitEditFieldLabel = uilabel(app.PostprocessingTab);
            app.xlimupperlimitEditFieldLabel.Text = 'xlim upper limit:';
            app.xlimupperlimitEditField = uieditfield(app.PostprocessingTab, 'text');
            app.xlimupperlimitEditField.ValueChangedFcn = createCallbackFcn(app, @xlimupperlimitEditFieldValueChanged, true);
            app.xlimupperlimitEditField.ValueChangingFcn = createCallbackFcn(app, @xlimupperlimitEditFieldValueChanging, true);
            app.ResetAxisLimitsButton = uibutton(app.PostprocessingTab, 'push');
            app.ResetAxisLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAxisLimitsButtonPushed, true);
            app.ResetAxisLimitsButton.Text = 'Reset Axis Limits';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ECPTprefixEditFieldLabel = uilabel(app.SettingsTab);
            app.ECPTprefixEditFieldLabel.Text = 'EC PT prefix';
            app.ECPTprefixEditField = uieditfield(app.SettingsTab, 'text');
            app.ECPTprefixEditField.ValueChangedFcn = createCallbackFcn(app, @ECPTprefixEditFieldValueChanged, true);
            app.ECPTprefixEditField.Value = 'press_pressure_';
            app.GSEControllerPTprefixEditFieldLabel = uilabel(app.SettingsTab);
            app.GSEControllerPTprefixEditFieldLabel.Text = 'GSE Controller PT prefix';
            app.GSEControllerPTprefixEditField = uieditfield(app.SettingsTab, 'text');
            app.GSEControllerPTprefixEditField.ValueChangedFcn = createCallbackFcn(app, @GSEControllerPTprefixEditFieldValueChanged, true);
            app.GSEControllerPTprefixEditField.Value = 'gse_pressure_';
            app.GSEControllerPTsuffixEditFieldLabel = uilabel(app.SettingsTab);
            app.GSEControllerPTsuffixEditFieldLabel.Text = 'GSE Controller PT suffix';
            app.GSEControllerPTsuffixEditField = uieditfield(app.SettingsTab, 'text');
            app.GSEControllerPTsuffixEditField.ValueChangedFcn = createCallbackFcn(app, @GSEControllerPTsuffixEditFieldValueChanged, true);
            app.GSEControllerPTsuffixEditField.Value = '__psi_';
            app.ECPTsuffixEditFieldLabel = uilabel(app.SettingsTab);
            app.ECPTsuffixEditFieldLabel.Text = 'EC PT suffix';
            app.ECPTsuffixEditField = uieditfield(app.SettingsTab, 'text');
            app.ECPTsuffixEditField.ValueChangedFcn = createCallbackFcn(app, @ECPTsuffixEditFieldValueChanged, true);
            app.ECPTsuffixEditField.Value = '__psi_';
            app.ECValvesuffixEditFieldLabel = uilabel(app.SettingsTab);
            app.ECValvesuffixEditFieldLabel.Text = 'EC Valve suffix';
            app.ECValvesuffixEditField = uieditfield(app.SettingsTab, 'text');
            app.ECValvesuffixEditField.ValueChangedFcn = createCallbackFcn(app, @ECValvesuffixEditFieldValueChanged, true);
            app.ECValvesuffixEditField.Value = '_en_ul_';
            app.ECValveprefixEditFieldLabel = uilabel(app.SettingsTab);
            app.ECValveprefixEditFieldLabel.Text = 'EC Valve prefix';
            app.ECValveprefixEditField = uieditfield(app.SettingsTab, 'text');
            app.ECValveprefixEditField.ValueChangedFcn = createCallbackFcn(app, @ECValveprefixEditFieldValueChanged, true);
            app.ECValveprefixEditField.Value = 'press_vlv';
            app.GSEControllerValvesuffixEditFieldLabel = uilabel(app.SettingsTab);
            app.GSEControllerValvesuffixEditFieldLabel.Text = 'GSE Controller Valve suffix';
            app.GSEControllerValvesuffixEditField = uieditfield(app.SettingsTab, 'text');
            app.GSEControllerValvesuffixEditField.ValueChangedFcn = createCallbackFcn(app, @GSEControllerValvesuffixEditFieldValueChanged, true);
            app.GSEControllerValvesuffixEditField.Value = '_en_ul_';
            app.GSEControllerValveprefixEditFieldLabel = uilabel(app.SettingsTab);
            app.GSEControllerValveprefixEditFieldLabel.Text = 'GSE Controller Valve prefix';
            app.GSEControllerValveprefixEditField = uieditfield(app.SettingsTab, 'text');
            app.GSEControllerValveprefixEditField.ValueChangedFcn = createCallbackFcn(app, @GSEControllerValveprefixEditFieldValueChanged, true);
            app.GSEControllerValveprefixEditField.Value = 'gse_vlv';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end